classdef VascularDataViz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        menu                          matlab.ui.container.Menu
        menu_loadAngiogram            matlab.ui.container.Menu
        menu_loadGraph                matlab.ui.container.Menu
        menu_loadStallsInfo           matlab.ui.container.Menu
        LoadpO2ptsMenu                matlab.ui.container.Menu
        SaveBranchOrderMenu           matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        GetbranchorderMenuforpO2Menu  matlab.ui.container.Menu
        GetpathbetweenpointsMenu      matlab.ui.container.Menu
        axes1                         matlab.ui.control.UIAxes
        edit_zStartIdx                matlab.ui.control.EditField
        edit_zEndIdx                  matlab.ui.control.EditField
        edit_xStartIdx                matlab.ui.control.EditField
        edit_xEndIdx                  matlab.ui.control.EditField
        edit_yStartIdx                matlab.ui.control.EditField
        edit_yEndIdx                  matlab.ui.control.EditField
        text2                         matlab.ui.control.Label
        text3                         matlab.ui.control.Label
        text4                         matlab.ui.control.Label
        AdjustcontrastPanel           matlab.ui.container.Panel
        minEditFieldLabel             matlab.ui.control.Label
        minEditField                  matlab.ui.control.NumericEditField
        maxEditFieldLabel             matlab.ui.control.Label
        maxEditField                  matlab.ui.control.NumericEditField
        ZstartSliderLabel             matlab.ui.control.Label
        ZstartSlider                  matlab.ui.control.Slider
        DisplaystallsCheckBox         matlab.ui.control.CheckBox
        DisplaypO2ptsCheckBox         matlab.ui.control.CheckBox
        GraphPanel                    matlab.ui.container.Panel
        DisplayGraphCheckBox          matlab.ui.control.CheckBox
        DisplayCapillaryCheckBox      matlab.ui.control.CheckBox
        DisplayArteryCheckBox         matlab.ui.control.CheckBox
        DisplayVeinCheckBox           matlab.ui.control.CheckBox
        DisplayAVpathCheckBox         matlab.ui.control.CheckBox
        EditField_2Label              matlab.ui.control.Label
        EditField_2                   matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        moveXleft                     matlab.ui.control.Button
        moveXright                    matlab.ui.control.Button
        moveYup                       matlab.ui.control.Button
        moveYdown                     matlab.ui.control.Button
        moveZtop                      matlab.ui.control.Button
        moveZbottom                   matlab.ui.control.Button
        ABL                           matlab.ui.control.Label
        ABL_um                        matlab.ui.control.Label
        VBL                           matlab.ui.control.Label
        VBL_um                        matlab.ui.control.Label
        ABOLabel                      matlab.ui.control.Label
        VBOLabel                      matlab.ui.control.Label
        SessionLabel                  matlab.ui.control.Label
        NumberLabel                   matlab.ui.control.Label
        StartpositionLabel            matlab.ui.control.Label
        WidthLabel                    matlab.ui.control.Label
        popupfigureCheckBox           matlab.ui.control.CheckBox
        InspectedCheckBox             matlab.ui.control.CheckBox
        NotesTextAreaLabel            matlab.ui.control.Label
        NotesTextArea                 matlab.ui.control.TextArea
        displaycurrentAVpathButton    matlab.ui.control.Button
        DisplaycappathCheckBox        matlab.ui.control.CheckBox
        EditField_3Label              matlab.ui.control.Label
        cappathField1                 matlab.ui.control.NumericEditField
        EditField_4Label              matlab.ui.control.Label
        cappathField2                 matlab.ui.control.NumericEditField
        BranchOrderLabel              matlab.ui.control.Label
        lengthLabel                   matlab.ui.control.Label
        IntraCapBranchOrder           matlab.ui.control.Label
        IntraCapBranchLength          matlab.ui.control.Label
    end

    
    methods (Access = private)
        function draw(app, event)
            % This function updates the display.
            global cVD
            
            % size of the angiogram
            [sZ,sY,sX] = size(cVD.angio);
            
            % read display range
            xStartIdx = min(max(str2double(get(app.edit_xStartIdx,'Value')),1),sX);
            xMIP = str2double(get(app.edit_xEndIdx,'Value'));
            xEndIdx = min(max(xStartIdx+xMIP-1,xStartIdx),sX);
            yMIP = str2double(get(app.edit_yEndIdx,'Value'));
            yStartIdx = min(max(str2double(get(app.edit_yStartIdx,'Value')),1),sY);
            yEndIdx = min(max(yStartIdx+yMIP-1,yStartIdx),sY);
            zStartIdx = min(max(str2double(get(app.edit_zStartIdx,'Value')),1),sZ);
            zMIP = str2double(get(app.edit_zEndIdx,'Value'));
            zEndIdx = min(max(zStartIdx+zMIP-1,zStartIdx),sZ);
            
            % read contrast values for display
            minValue = get(app.minEditField,'value');
            maxValue = get(app.maxEditField,'value');
            
            % get MIP of angiogram range for the specified display range
            img = squeeze(max(cVD.angio(zStartIdx:zEndIdx,:,:),[],1));
            
            % display image
            if get(app.popupfigureCheckBox,'Value')
                figure_handle = figure(1);
                imagesc(img,[minValue maxValue])
                figure_axis = gca;
                set(figure_handle,'KeyPressFcn',{@myfun,app});
                colormap('gray')
                axis('image')
                xlim([xStartIdx xEndIdx])
                ylim([yStartIdx yEndIdx])
            else
                imagesc(img,'parent',app.axes1,[minValue maxValue])
                colormap(app.axes1,'gray')
                axis(app.axes1,'image')
                xlim(app.axes1,[xStartIdx xEndIdx])
                ylim(app.axes1,[yStartIdx yEndIdx])
                figure_axis = app.axes1;
            end
            % display stalls
            if get(app.DisplaystallsCheckBox,'Value')
                if ~isfield(cVD,'stalls')
                    h= msgbox('Please load stall-O-gram data to visulaize stalls');
                    uiwait(h);
                else
                    for u = 1:length(cVD.stalls.seg)
                        if (cVD.stalls.pts(u,3) >= zStartIdx && cVD.stalls.pts(u,3) <= zEndIdx)
                            hold(figure_axis,'on')
                            plot(cVD.stalls.seg(u).pos(1:end-1,3),cVD.stalls.seg(u).pos(1:end-1,2),'parent',figure_axis,'Color',[1 0 0],'MarkerSize',15);
                            hold(figure_axis,'off')
                        end
                    end
                end
            end
            
            % display pO2 points
            if get(app.DisplaypO2ptsCheckBox,'Value')
                colors{1} = 'green'; colors{2} = 'cyan'; colors{3} = 'yellow';
                if isfield(cVD,'pO2pts')
%                     if get(app.popupfigureCheckBox,'Value')
%                         for v = 1:length(cVD.pO2pts)
%                             pts = cVD.pO2pts{v};
%                             for u = 1:size(pts,1)
%                                 hold('on')
%                                 if pts(u,1) >= zStartIdx && pts(u,1) <= zEndIdx && pts(u,2) >= xStartIdx && pts(u,2) <= xEndIdx && pts(u,3) >= yStartIdx && pts(u,3) <= yEndIdx
%                                     text(pts(u,2),pts(u,3),['\leftarrow' num2str(u)],'Color',colors{v},'FontSize',14);
%                                 end
%                                 hold('off')
%                             end
%                         end
%                     else
                        for v = 1:length(cVD.pO2pts)
                            pts = cVD.pO2pts{v};
                            for u = 1:size(pts,1)
                                hold(figure_axis,'on')
                                if pts(u,1) >= zStartIdx && pts(u,1) <= zEndIdx && pts(u,2) >= xStartIdx && pts(u,2) <= xEndIdx && pts(u,3) >= yStartIdx && pts(u,3) <= yEndIdx
                                    text(pts(u,2),pts(u,3),['\leftarrow' num2str(u)],'parent',figure_axis,'Color',colors{v},'FontSize',14);
                                end
                                hold(figure_axis,'off')
                            end
                        end
%                     end
                end
            end
            
            % display Graph
            if get(app.DisplayGraphCheckBox,'Value')
                
                nodes = cVD.Graph.nodes;
                edges = cVD.Graph.edges;
                
                if get(app.DisplayCapillaryCheckBox,'value')
                    lst = find(nodes(:,1)>=xStartIdx & nodes(:,1)<=xEndIdx & ...
                        nodes(:,2)>=yStartIdx & nodes(:,2)<=yEndIdx & ...
                        nodes(:,3)>=zStartIdx & nodes(:,3)<=zEndIdx );
                    
                    hold(figure_axis,'on')
                    
                    plot(cVD.Graph.nodes(lst,1),cVD.Graph.nodes(lst,2),'g.','parent',figure_axis);
                    hold(figure_axis,'off')
                    
                end
                
                if isfield(cVD.Graph,'nodeType')
                    if get(app.DisplayArteryCheckBox,'value')
                        lstArtery = find(nodes(:,1)>=xStartIdx & nodes(:,1)<=xEndIdx & ...
                            nodes(:,2)>=yStartIdx & nodes(:,2)<=yEndIdx & ...
                            nodes(:,3)>=zStartIdx  & nodes(:,3)<=zEndIdx & ...
                            cVD.Graph.nodeType == 1);
                        
                        hold(figure_axis,'on')
                        plot(cVD.Graph.nodes(lstArtery,1),cVD.Graph.nodes(lstArtery,2),'r.','parent',figure_axis);
                        hold(figure_axis,'off')
                    end
                    
                    if get(app.DisplayVeinCheckBox,'value')
                        lstVein = find(nodes(:,1)>=xStartIdx & nodes(:,1)<=xEndIdx & ...
                            nodes(:,2)>=yStartIdx & nodes(:,2)<=yEndIdx & ...
                            nodes(:,3)>=zStartIdx  & nodes(:,3)<=zEndIdx & ...
                            cVD.Graph.nodeType == 3);
                        
                        hold(figure_axis,'on')
                        plot(cVD.Graph.nodes(lstVein,1),cVD.Graph.nodes(lstVein,2),'b.','parent',figure_axis);
                        hold(figure_axis,'off')
                    end
                end
            end
            
            % display shortest artery to vein path
            if get(app.DisplayAVpathCheckBox,'Value')
                if isfield(cVD,'pO2ptsBO')
                    po2pt = get(app.EditField_2,'Value');
                    session = app.EditField.Value;
                    pO2ptsBO = cVD.pO2ptsBO{session};
                    segs = pO2ptsBO(po2pt).segs;
                    segnodes = [];
                    colors{1} = 'g.'; colors{2} = 'c.'; colors{3} = 'y.';
                    for u = 1:length(segs)
                        segnodes = [segnodes; find(cVD.Graph.segInfo.nodeSegN == segs(u))];
                    end
                    segnodes = unique(segnodes);
                    lst = find(nodes(segnodes,1)>=xStartIdx & nodes(segnodes,1)<=xEndIdx & ...
                            nodes(segnodes,2)>=yStartIdx & nodes(segnodes,2)<=yEndIdx & ...
                            nodes(segnodes,3)>=zStartIdx & nodes(segnodes,3)<=zEndIdx );
                    hold(figure_axis,'on')
                    plot(cVD.Graph.nodes(segnodes(lst),1),cVD.Graph.nodes(segnodes(lst),2),colors{session},'parent',figure_axis);
                    hold(figure_axis,'off')
                    set(app.ABL,'Text',num2str(pO2ptsBO(po2pt).AsegsLength))
                    set(app.ABL_um,'Text',[num2str(pO2ptsBO(po2pt).AsegsLength_um) ' um'])
                    set(app.VBL,'Text',num2str(pO2ptsBO(po2pt).VsegsLength))
                    set(app.VBL_um,'Text',[num2str(pO2ptsBO(po2pt).VsegsLength_um) ' um'])
                    
                    % display inspection and notes information
                    if isfield(pO2ptsBO,'inspected')
                        if pO2ptsBO(po2pt).inspected == 1
                            set(app.InspectedCheckBox,'Value',1)
                        else
                            set(app.InspectedCheckBox,'Value',0)
                        end
                    end
                    if isfield(pO2ptsBO,'notes')
                        if ~isempty(pO2ptsBO(po2pt).notes) 
                            set(app.NotesTextArea,'Value',pO2ptsBO(po2pt).notes)
                        else
                            set(app.NotesTextArea,'Value','')
                        end
                    end
                end
            end
            
            % display path between points
            if get(app.DisplaycappathCheckBox,'Value')
                if isfield(cVD,'IntrapO2ptspath')
                     startCap =  app.cappathField1.Value;
                     targetCap = app.cappathField2.Value;
                     min_pt = min(startCap,targetCap);
                     max_pt = max(startCap,targetCap);
                     if min_pt ~= max_pt
                        segs = cVD.IntrapO2ptspath{1,min_pt,max_pt-min_pt}.segs;
    %                     segs(1) = [];
    %                     segs(end) = [];
                        segnodes = [];
                        colors{1} = 'g.'; colors{2} = 'c.'; colors{3} = 'y.';
                        for u = 1:length(segs)
                            segnodes = [segnodes; find(cVD.Graph.segInfo.nodeSegN == segs(u))];
                        end
                        segnodes = unique(segnodes);
                        lst = find(nodes(segnodes,1)>=xStartIdx & nodes(segnodes,1)<=xEndIdx & ...
                                nodes(segnodes,2)>=yStartIdx & nodes(segnodes,2)<=yEndIdx & ...
                                nodes(segnodes,3)>=zStartIdx & nodes(segnodes,3)<=zEndIdx );
                        hold(figure_axis,'on')
                        plot(cVD.Graph.nodes(segnodes(lst),1),cVD.Graph.nodes(segnodes(lst),2),colors{2},'parent',figure_axis);
                        hold(figure_axis,'off')
                        set(app.IntraCapBranchOrder,'Text',num2str(cVD.IntrapO2ptspath{1,min_pt,max_pt-min_pt}.branchorder))
                        set(app.IntraCapBranchLength,'Text',[num2str(cVD.IntrapO2ptspath{1,min_pt,max_pt-min_pt}.segsLength) ' um'])
                    end
                end
            end
            
            % function to handle keyboard input
            function myfun(src,event,app)
                if strcmp(event.Key,'w')
                    yMIP = str2double(get(app.edit_yEndIdx,'Value'));
                    yStartIdx = min(max(str2double(get(app.edit_yStartIdx,'Value')),1),sY);
                    yEndIdx = min(max(yStartIdx+yMIP-1,yStartIdx),sY);
                    width = yEndIdx - yStartIdx;
                    yStartIdx = round(yStartIdx-width*0.1);
                    set(app.edit_yStartIdx,'Value',num2str(yStartIdx))
                    draw(app,event);
                elseif strcmp(event.Key,'s')
                    yMIP = str2double(get(app.edit_yEndIdx,'Value'));
                    yStartIdx = min(max(str2double(get(app.edit_yStartIdx,'Value')),1),sY);
                    yEndIdx = min(max(yStartIdx+yMIP-1,yStartIdx),sY);
                    width = yEndIdx - yStartIdx;
                    yStartIdx = round(yStartIdx+width*0.1);
                    set(app.edit_yStartIdx,'Value',num2str(yStartIdx))
                    draw(app,event);
                elseif strcmp(event.Key,'a')
                    xStartIdx = min(max(str2double(get(app.edit_xStartIdx,'Value')),1),sX);
                    xMIP = str2double(get(app.edit_xEndIdx,'Value'));
                    xEndIdx = min(max(xStartIdx+xMIP-1,xStartIdx),sX);
                    width = xEndIdx - xStartIdx;
                    xStartIdx = round(xStartIdx-width*0.1);
                    set(app.edit_xStartIdx,'Value',num2str(xStartIdx))
                    draw(app,event);
                elseif strcmp(event.Key,'d')
                    xStartIdx = min(max(str2double(get(app.edit_xStartIdx,'Value')),1),sX);
                    xMIP = str2double(get(app.edit_xEndIdx,'Value'));
                    xEndIdx = min(max(xStartIdx+xMIP-1,xStartIdx),sX);
                    width = xEndIdx - xStartIdx;
                    xStartIdx = round(xStartIdx+width*0.1);
                    set(app.edit_xStartIdx,'Value',num2str(xStartIdx))
                    draw(app,event);
                elseif strcmp(event.Key,'q')
                    [sZ,sX,sY] = size(cVD.angio);
                    zStartIdx = min(max(str2double(get(app.edit_zStartIdx,'Value')),1),sZ);
                    zMIP = str2double(get(app.edit_zEndIdx,'Value'));
                    zEndIdx = min(max(zStartIdx+zMIP-1,zStartIdx),sZ);
                    width = zEndIdx - zStartIdx;
                    zStartIdx = round(zStartIdx-width*0.1);
                    set(app.edit_zStartIdx,'Value',num2str(zStartIdx))
                    draw(app,event);
                elseif strcmp(event.Key,'e')
                    [sZ,sX,sY] = size(cVD.angio);
                    zStartIdx = min(max(str2double(get(app.edit_zStartIdx,'Value')),1),sZ);
                    zMIP = str2double(get(app.edit_zEndIdx,'Value'));
                    zEndIdx = min(max(zStartIdx+zMIP-1,zStartIdx),sZ);
                    width = zEndIdx - zStartIdx;
                    zStartIdx = round(zStartIdx+width*0.1);
                    set(app.edit_zStartIdx,'Value',num2str(zStartIdx))
                    draw(app,event);
                end
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function chronicVascularDataViz_OpeningFcn(app)
            %             % This function has no output args, see OutputFcn.
            %             % hObject    handle to figure
            %             % eventdata  reserved - to be defined in a future version of MATLAB
            %             % handles    structure with handles and user data (see GUIDATA)
            %             % varargin   command line arguments to chronicVascularDataViz (see VARARGIN)
            %
            %             % Choose default command line output for chronicVascularDataViz
            %             app.output = hObject;
            %
            %             % Update handles structure
            %             guidata(hObject, handles);
        end

        % Menu selected function: menu_loadAngiogram
        function menu_loadAngiogram_Callback(app, event)
            % hObject    handle to menu_loadAngiogram (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global cVD
            
            [filename,pathname] = uigetfile({'*.mat;*.tiff;*.tif'},'Please select angiogram');
            if filename == 0
                return
            end
            h = waitbar(0,'Please wait... loading the Angiogram');
            [pathstr,name,ext] = fileparts(filename);
            if strcmp(ext,'.mat')
                temp = load([pathname filename]);
                fn = fieldnames(temp);
                angio = temp.(fn{1});
            elseif strcmp(ext,'.tiff') || strcmp(ext,'.tif')
                info = imfinfo([pathname filename]);
                for u = 1:length(info)
                    if u == 1
                        temp = imread([pathname filename],1);
                        angio = zeros([length(info) size(temp)]);
                        angio(u,:,:) = temp;
                    else
                        angio(u,:,:) = imread([pathname filename],u);
                    end
                end
            end
            [sZ,sY,sX] = size(angio);
            
            % store file path info
            cVD.filename = filename;
            cVD.pathname = pathname;
            
            % Set display indices
            set(app.edit_zStartIdx,'Value',num2str(1));
            set(app.edit_zEndIdx,'Value',num2str(sZ));
            set(app.edit_xStartIdx,'Value',num2str(1));
            set(app.edit_xEndIdx,'Value',num2str(sX));
            set(app.edit_yStartIdx,'Value',num2str(1));
            set(app.edit_yEndIdx,'Value',num2str(sY));
            
            % set indices labels
            set(app.text2,'Text',['Z: 1 to ' num2str(sZ)])
            set(app.text3,'Text',['X: 1 to ' num2str(sX)])
            set(app.text4,'Text',['Y: 1 to ' num2str(sY)])
            
            % Set contrast values
            set(app.minEditField,'Value',min(angio(:)));
            set(app.maxEditField,'Value',max(angio(:)));
            
            %set Zslider range and values
            set(app.ZstartSlider,'Limits',[1 sZ])
            set(app.ZstartSlider,'Value',1);
            
            cVD.angio = angio;
            close(h);
            
            draw(app,event);
        end

        % Value changed function: edit_xStartIdx
        function edit_xStartIdxValueChanged(app, event)
            value = app.edit_xStartIdx.Value;
            draw(app,event);
        end

        % Value changed function: edit_xEndIdx
        function edit_xEndIdxValueChanged(app, event)
            value = app.edit_xEndIdx.Value;
            draw(app,event);
        end

        % Value changed function: edit_yStartIdx
        function edit_yStartIdxValueChanged(app, event)
            value = app.edit_yStartIdx.Value;
            draw(app,event);
        end

        % Value changed function: edit_yEndIdx
        function edit_yEndIdxValueChanged(app, event)
            value = app.edit_yEndIdx.Value;
            draw(app,event);
        end

        % Value changed function: edit_zEndIdx
        function edit_zEndIdxValueChanged(app, event)
            value = app.edit_zEndIdx.Value;
            draw(app,event);
        end

        % Value changed function: edit_zStartIdx
        function edit_zStartIdxValueChanged(app, event)
            value = app.edit_zStartIdx.Value;
            set(app.ZstartSlider,'Value',str2double(value));
            draw(app,event);
        end

        % Menu selected function: menu_loadStallsInfo
        function menu_loadStallsInfoMenuSelected(app, event)
            global cVD
            
            % Load stall-O-gram data
            [filename,pathname] = uigetfile({'*.mat'},'Please select stall-O-gram data');
            if filename == 0
                return
            end
            if strcmp(filename,'Transformation.mat')
                load([pathname filename],'Transformation');
                load([pathname 'capStallOCT.mat'],'seg');
                load([pathname 'capStallOCT.mat'],'pts');
                cVD.stalls.seg = seg;
                cVD.stalls.pts = pts;
                for v = 1:length(Transformation.Tlist2to1)
                    if strcmp(Transformation.Tlist2to1{v},'L')
                        DestinationPts = Transformation.DestinationPts;
                        SourcePts = Transformation.SourcePts;
                        segnew = cVD.stalls.seg;
                        [~,~,TRANSFORM] = procrustes(DestinationPts,SourcePts);
                        for u = 1:length(seg)
                            pos = seg(u).pos;
                            posnew = pos;
                            segnew(u).pos = round(TRANSFORM.b * posnew * TRANSFORM.T + TRANSFORM.c(1,:));
                        end
                        cVD.stalls.seg = segnew;
                    elseif strcmp(Transformation.Tlist2to1{v},'NL-imregdemon')
                        segnew = cVD.stalls.seg;
                        D = Transformation.DisplacementField;
                        [Dx,Dy,Dz] = size(D);
                        for u = 1:length(seg)
                            for w= 1:size(seg(u).pos,1)
                                x = min(max(segnew(u).pos(w,1),1),Dx);
                                y = min(max(segnew(u).pos(w,2),1),Dy);
                                z = min(max(segnew(u).pos(w,3),1),Dz);
                                segnew(u).pos(w,1) = round(segnew(u).pos(w,1)-D(x,y,z,1));
                                segnew(u).pos(w,2) = round(segnew(u).pos(w,2)-D(x,y,z,2));
                                segnew(u).pos(w,3) = round(segnew(u).pos(w,3)-D(x,y,z,3));
                            end
                        end
                        cVD.stalls.seg = segnew;
                    end
                    %                     cVD.stalls.seg = seg;
                end
            else
                temp = load([pathname filename]);
                fn = fieldnames(temp);
                if ~isfield(cVD,'stalls')
                    cVD.stalls = [];
                end
                for u = 1:length(fn)
                    if strcmp('Cap',fn{u})
                        cVD.stalls.Cap = temp.(fn{u});
                    elseif strcmp('pts',fn{u})
                        cVD.stalls.pts = temp.(fn{u});
                    elseif strcmp('seg',fn{u})
                        cVD.stalls.seg = temp.(fn{u});
                    elseif strcmp('Int_ts',fn{u})
                        cVD.stalls.Int_ts = temp.(fn{u});
                    elseif strcmp('StallingMatrix',fn{u})
                        cVD.stalls.StallingMatrix = temp.(fn{u});
                    else
                        h = msgbox('Please select stalling data saved from capStall GUI');
                        uiwait(h);
                        return
                    end
                end
            end
            draw(app,event);
        end

        % Value changed function: minEditField
        function minEditFieldValueChanged(app, event)
            value = app.minEditField.Value;
            draw(app,event);
        end

        % Value changed function: maxEditField
        function maxEditFieldValueChanged(app, event)
            value = app.maxEditField.Value;
            draw(app,event);
        end

        % Value changed function: ZstartSlider
        function ZstartSliderValueChanged(app, event)
            value = app.ZstartSlider.Value;
            set(app.edit_zStartIdx,'Value',num2str(round(value)));
            set(app.ZstartSlider,'Value',round(value));
            draw(app,event);
        end

        % Value changed function: DisplaystallsCheckBox
        function DisplaystallsCheckBoxValueChanged(app, event)
            value = app.DisplaystallsCheckBox.Value;
            draw(app,event);
        end

        % Menu selected function: LoadpO2ptsMenu
        function LoadpO2ptsMenuSelected(app, event)
            global cVD
            
            % Load pO2 pts
            [filename,pathname] = uigetfile({'*.mat'},'Please select pO2 pts');
            if filename == 0
                return
            end
            temp = load([pathname filename]);
            fn = fieldnames(temp);
            if ~isfield(cVD,'pO2pts')
                cVD.pO2pts{1} = temp.(fn{1});
            else
                idx = length(cVD.pO2pts);
                cVD.pO2pts{idx+1} = temp.(fn{1});
            end
        end

        % Value changed function: DisplaypO2ptsCheckBox
        function DisplaypO2ptsCheckBoxValueChanged(app, event)
            value = app.DisplaypO2ptsCheckBox.Value;
            draw(app,event);
        end

        % Menu selected function: menu_loadGraph
        function menu_loadGraphMenuSelected(app, event)
          global cVD
            [filename,pathname] = uigetfile({'*.mat'},'Please select graph data');
            if filename == 0
                return
            end
            h = waitbar(0,'Please wait... loading the Angiogram');
            load([pathname filename])
            if exist('Graph','var')
                cVD.Graph = Graph;
                cVD.graphfilename = filename;
                cVD.graphpathname = pathname;
            end
            if exist('pO2ptsBO','var')
                load([pathname filename],'pO2ptsBO')
                cVD.pO2ptsBO = pO2ptsBO;
            end
            if exist('pO2Seg','var')
                load([pathname filename],'pO2Seg')
                cVD.pO2Seg = pO2Seg;
            end
            close(h);
            draw(app,event);
        end

        % Value changed function: DisplayGraphCheckBox
        function DisplayGraphCheckBoxValueChanged(app, event)
            value = app.DisplayGraphCheckBox.Value;
            draw(app,event);
        end

        % Value changed function: DisplayCapillaryCheckBox
        function DisplayCapillaryCheckBoxValueChanged(app, event)
            value = app.DisplayCapillaryCheckBox.Value;
            draw(app,event);
        end

        % Value changed function: DisplayArteryCheckBox
        function DisplayArteryCheckBoxValueChanged(app, event)
            value = app.DisplayArteryCheckBox.Value;
            draw(app,event);
        end

        % Value changed function: DisplayVeinCheckBox
        function DisplayVeinCheckBoxValueChanged(app, event)
            value = app.DisplayVeinCheckBox.Value;
            draw(app,event);
        end

        % Menu selected function: GetbranchorderMenuforpO2Menu
        function GetbranchorderMenuforpO2MenuSelected(app, event)
            global cVD
            if isfield(cVD,'pO2pts')
                if ~isfield(cVD,'pO2Seg') || (length(cVD.pO2pts) ~= length(cVD.pO2Seg))
                    for u = 1:length(cVD.pO2pts)
                       cVD.pO2Seg{u} = zeros(size(cVD.pO2pts{u},1),1);
                    end
                end
                for v = 1:length(cVD.pO2pts)
                    nodeType = cVD.Graph.nodeType;
                    segInfo = cVD.Graph.segInfo;
                    capSegments = [];
                    capSegmentsBranchOrder = [];
                    branchcount = 0;
                    cap  = [];
                    pts = cVD.pO2pts{v};
                    for uz = 1:size(pts,1)
                        uz
                        pO2_pos = pts(uz,:);
                        pO2_pos = [pO2_pos(2) pO2_pos(3) pO2_pos(1)];
                        [~,nodeIdx] = min(sqrt(sum((cVD.Graph.nodes-pO2_pos).^2,2)));
                        nodeIdx
                        ss = cVD.Graph.segInfo.nodeSegN(nodeIdx); 
                        ss
                        cVD.pO2Seg{v}(uz) = ss;
                        currentSegNodes = find(segInfo.nodeSegN == ss);
                        if ~isempty(currentSegNodes)
                            if nodeType(currentSegNodes(1)) == 2
                                branchcount = branchcount+1;
                                %Startnode = 5679;
                                
                                segment = ss;% find which segment this node belows to
                                % n = 9; % beanch order
                                capSegments = [capSegments; segment];
                                % starting segment
                                segment
                                Start_segEndNodes = segInfo.segEndNodes(segment,:);
                                ArteryPathSide1 = [];
                                ArteryPathSide2 = [];
                                VeinPathSide1 = [];
                                VeinPathSide2 = [];
                                for u = 1:length(Start_segEndNodes)
                                    processedSegments = [];
                                    processedEndNodes = [];
                                    branchOrder = zeros(1,size(segInfo.segEndNodes,1));
                                    branchOrder(segment) = 1;
                                    currentSegment = segment;
                                    segEndNodes = Start_segEndNodes(u);
                                    count = 1;
                                    breakfromloop = 0;
                                    foundArtery = 0;
                                    foundVein = 0;
                                    while count < 20
                                        if count > 1
                                            segEndNodes = segInfo.segEndNodes(currentSegment,:);
                                        end
                                        segEndNodes = setdiff(segEndNodes,processedEndNodes);
                                        segEndNodes = segEndNodes(:);
                                        segmentsNextorder  = [];
                                        for vv=1:length(segEndNodes)
                                            segmentsNextorder = [segmentsNextorder ; find((segInfo.segEndNodes(:,1) == segEndNodes(vv)) | (segInfo.segEndNodes(:,2) == segEndNodes(vv)))];
                                        end
                                        processedSegments = [processedSegments; currentSegment];
                                        processedEndNodes = [processedEndNodes; segEndNodes];
                                        currentSegment = setdiff(segmentsNextorder,processedSegments);
                                        count = count+1;
                                        branchOrder(currentSegment) = count;
                                        for ww = 1:length(currentSegment)
                                            currentSegNodes = find(segInfo.nodeSegN == currentSegment(ww));
                                            if ~isempty(currentSegNodes)
                                                if (foundArtery ==0) & nodeType(currentSegNodes(1)) == 1
                                                    foundArtery = 1;
                                                    if u == 1
                                                        ArteryPathSide1 = currentSegment(ww);
                                                        for zz = 1:count-1
                                                            segs = find(branchOrder == count-zz);
                                                            current_endNodes = segInfo.segEndNodes(ArteryPathSide1(end),:);
                                                            for kk = 1:length(segs)
                                                                segs_endNodes = segInfo.segEndNodes(segs(kk),:);
                                                                [lia1,locb1] = ismember(current_endNodes,segs_endNodes);
                                                                if sum(lia1) > 0
                                                                    ArteryPathSide1 = [ArteryPathSide1; segs(kk)];
                                                                    break;
                                                                end
                                                            end
                                                        end
                                                    elseif u == 2
                                                        ArteryPathSide2 = currentSegment(ww);
                                                        for zz = 1:count-1
                                                            segs = find(branchOrder == count-zz);
                                                            current_endNodes = segInfo.segEndNodes(ArteryPathSide2(end),:);
                                                            for kk = 1:length(segs)
                                                                segs_endNodes = segInfo.segEndNodes(segs(kk),:);
                                                                [lia1,locb1] = ismember(current_endNodes,segs_endNodes);
                                                                if sum(lia1) > 0
                                                                    ArteryPathSide2 = [ArteryPathSide2; segs(kk)];
                                                                    break;
                                                                end
                                                            end
                                                        end
                                                    end
                                                end
                                                if (foundVein ==0) & nodeType(currentSegNodes(1)) == 3
                                                    foundVein  = 1;
                                                    if u == 1
                                                        VeinPathSide1 = currentSegment(ww);
                                                        for zz = 1:count-1
                                                            segs = find(branchOrder == count-zz);
                                                            current_endNodes = segInfo.segEndNodes(VeinPathSide1(end),:);
                                                            for kk = 1:length(segs)
                                                                segs_endNodes = segInfo.segEndNodes(segs(kk),:);
                                                                [lia1,locb1] = ismember(current_endNodes,segs_endNodes);
                                                                if sum(lia1) > 0
                                                                    VeinPathSide1 = [VeinPathSide1; segs(kk)];
                                                                    break;
                                                                end
                                                            end
                                                        end
                                                    elseif u ==2
                                                        VeinPathSide2 = currentSegment(ww);
                                                        for zz = 1:count-1
                                                            segs = find(branchOrder == count-zz);
                                                            current_endNodes = segInfo.segEndNodes(VeinPathSide2(end),:);
                                                            for kk = 1:length(segs)
                                                                segs_endNodes = segInfo.segEndNodes(segs(kk),:);
                                                                [lia1,locb1] = ismember(current_endNodes,segs_endNodes);
                                                                if sum(lia1) > 0
                                                                    VeinPathSide2 = [VeinPathSide2; segs(kk)];
                                                                    break;
                                                                end
                                                            end
                                                        end
                                                    end
                                                end
                                            end
                                            if  foundArtery & foundVein
                                                break;
                                            end
                                        end
                                        if  foundArtery & foundVein
                                            break;
                                        end
                                        %                     count = count+1;
                                    end
                                end
                                %              branchLen2 = length(ArteryPathSide2)+length(VeinPathSide1)-1;
                                %              branchLen1 = length(ArteryPathSide1)+length(VeinPathSide2)-1;
                                
                                if ~isempty(ArteryPathSide1) & ~isempty(VeinPathSide2)
                                    branchLen1 = length(ArteryPathSide1)+length(VeinPathSide2)-1;
                                    b1 = 1;
                                else
                                    %                   branchLen1 = length(ArteryPathSide1)+length(VeinPathSide2);
                                    branchLen1 = 0;
                                    b1 = 1;
                                    
                                end
                                if ~isempty(ArteryPathSide2) & ~isempty(VeinPathSide1)
                                    branchLen2 = length(ArteryPathSide2)+length(VeinPathSide1)-1;
                                    b2 =1;
                                else
                                    %                   branchLen2 = length(ArteryPathSide2)+length(VeinPathSide1);
                                    branchLen2 =0;
                                    b2 = 0;
                                end
                                
                                if ~xor(b1,b2)
                                    if branchLen1 <= branchLen2
                                        cap(branchcount).segs = [ArteryPathSide1(2:end-1); VeinPathSide2(2:end)];
                                        branchLen = length(cap(branchcount).segs);
                                        cap(branchcount).Asegs = ArteryPathSide1(2:end-1);
                                        cap(branchcount).AsegsLength = length(ArteryPathSide1(2:end-1));
                                        cap(branchcount).AsegsLength_um = sum(segInfo.segLen(ArteryPathSide1(2:end-1)));
                                        cap(branchcount).Vsegs = VeinPathSide2(2:end-1);
                                        cap(branchcount).VsegsLength = length(VeinPathSide2(2:end-1));
                                        cap(branchcount).VsegsLength_um = sum(segInfo.segLen(VeinPathSide2(2:end-1)));
                                        %                        if ~isempty(ArteryPathSide1) & ~isempty(VeinPathSide2)
                                        %                            cap(branchcount).segs = [ArteryPathSide1(2:end-1); VeinPathSide2(2:end)];
                                        %                        else
                                        %                            cap(branchcount).segs = [];
                                        %                        end
                                    else
                                        cap(branchcount).segs = [ArteryPathSide2(2:end-1); VeinPathSide1(2:end)];
                                        branchLen = length(cap(branchcount).segs);
                                        cap(branchcount).Asegs = ArteryPathSide2(2:end-1);
                                        cap(branchcount).AsegsLength = length(ArteryPathSide2(2:end-1));
                                        cap(branchcount).AsegsLength_um = sum(segInfo.segLen(ArteryPathSide2(2:end-1)));
                                        cap(branchcount).Vsegs = VeinPathSide1(2:end-1);
                                        cap(branchcount).VsegsLength = length(VeinPathSide1(2:end-1));
                                        cap(branchcount).VsegsLength_um = sum(segInfo.segLen(VeinPathSide1(2:end-1)));
                                        %                       if ~isempty(ArteryPathSide2) & ~isempty(VeinPathSide1)
                                        %                            cap(branchcount).segs = [ArteryPathSide2(2:end-1); VeinPathSide1(2:end)];
                                        %                       else
                                        %                           %  branchLen2 = length(ArteryPathSide2)+length(VeinPathSide1);
                                        %                           cap(branchcount).segs = [];
                                        %                           branchLen2 =0;
                                        %                       end
                                    end
                                else
                                    if b1 == 0
                                        cap(branchcount).segs = [ArteryPathSide2(2:end-1); VeinPathSide1(2:end)];
                                        branchLen = length(cap(branchcount).segs);
                                        cap(branchcount).Asegs = ArteryPathSide2(2:end-1);
                                        cap(branchcount).AsegsLength = length(ArteryPathSide2(2:end-1));
                                        cap(branchcount).AsegsLength_um = sum(segInfo.segLen(ArteryPathSide2(2:end-1)));
                                        cap(branchcount).Vsegs = VeinPathSide1(2:end-1);
                                        cap(branchcount).VsegsLength = length(VeinPathSide1(2:end-1));
                                        cap(branchcount).VsegsLength_um = sum(segInfo.segLen(VeinPathSide1(2:end-1)));
                                    else
                                        cap(branchcount).segs = [ArteryPathSide1(2:end-1); VeinPathSide2(2:end)];
                                        branchLen = length(cap(branchcount).segs);
                                        cap(branchcount).Asegs = ArteryPathSide1(2:end-1);
                                        cap(branchcount).AsegsLength = length(ArteryPathSide1(2:end-1));
                                        cap(branchcount).AsegsLength_um = sum(segInfo.segLen(ArteryPathSide1(2:end-1)));
                                        cap(branchcount).Vsegs = VeinPathSide2(2:end-1);
                                        cap(branchcount).VsegsLength = length(VeinPathSide2(2:end-1));
                                        cap(branchcount).VsegsLength_um = sum(segInfo.segLen(VeinPathSide2(2:end-1)));
                                    end
                                end
                                cap(branchcount).segsLength = sum(segInfo.segLen(cap(branchcount).segs));
                                capSegmentsBranchOrder = [capSegmentsBranchOrder; branchLen];
                            end
                        end
                    end
                    if ~isfield(cVD,'pO2ptsBO')
                        cVD.pO2ptsBO{1} = cap;
                    else
                        idx = length(cVD.pO2ptsBO);
                        cVD.pO2ptsBO{idx+1} = cap;
                    end
                end
                
            end
        end

        % Value changed function: DisplayAVpathCheckBox
        function DisplayAVpathCheckBoxValueChanged(app, event)
            global cVD
            if isfield(cVD,'pO2ptsBO')
                value = app.DisplayAVpathCheckBox.Value;
                session = app.EditField.Value;
                if get(app.DisplayAVpathCheckBox,'Value')
                    set(app.SessionLabel,'Text',['Session(' num2str(size(cVD.pO2ptsBO,2)) ')']);
                    pO2ptsBO = cVD.pO2ptsBO{session};
                    set(app.NumberLabel,'Text',['number(' num2str(size(pO2ptsBO,2)) ')']);
                end
            end
            draw(app,event);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            global cVD
            value = app.EditField_2.Value;
            session = app.EditField.Value;
            session = max(min(session,length(cVD.pO2pts)),1);
            pts = cVD.pO2pts{session};
            value = max(min(value,size(pts,1)),1);
            set(app.EditField_2,'Value',value);
            draw(app,event);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            global cVD
            value = app.EditField.Value;
            value = max(min(value,length(cVD.pO2pts)),1);
            set(app.EditField,'value',value);
            pO2ptsBO = cVD.pO2ptsBO{value};
            set(app.NumberLabel,'Text',['number(' num2str(size(pO2ptsBO,2)) ')']);
            draw(app,event);
        end

        % Button pushed function: moveXleft
        function moveXleftButtonPushed(app, event)
            global cVD
            
            % size of the angiogram
            [sZ,sX,sY] = size(cVD.angio);
            
            xStartIdx = min(max(str2double(get(app.edit_xStartIdx,'Value')),1),sX);
            xMIP = str2double(get(app.edit_xEndIdx,'Value'));
            xEndIdx = min(max(xStartIdx+xMIP-1,xStartIdx),sX);
            width = xEndIdx - xStartIdx;
            xStartIdx = round(xStartIdx-width*0.1);
            set(app.edit_xStartIdx,'Value',num2str(xStartIdx))
            draw(app,event);
        end

        % Button pushed function: moveXright
        function moveXrightButtonPushed(app, event)
             global cVD
            
            % size of the angiogram
            [sZ,sX,sY] = size(cVD.angio);
            
            xStartIdx = min(max(str2double(get(app.edit_xStartIdx,'Value')),1),sX);
            xMIP = str2double(get(app.edit_xEndIdx,'Value'));
            xEndIdx = min(max(xStartIdx+xMIP-1,xStartIdx),sX);
            width = xEndIdx - xStartIdx;
            xStartIdx = round(xStartIdx+width*0.1);
            set(app.edit_xStartIdx,'Value',num2str(xStartIdx))
            draw(app,event);
        end

        % Button pushed function: moveYup
        function moveYupButtonPushed(app, event)
            global cVD
            
            % size of the angiogram
            [sZ,sX,sY] = size(cVD.angio);
            
            yMIP = str2double(get(app.edit_yEndIdx,'Value'));
            yStartIdx = min(max(str2double(get(app.edit_yStartIdx,'Value')),1),sY);
            yEndIdx = min(max(yStartIdx+yMIP-1,yStartIdx),sY);
            width = yEndIdx - yStartIdx;
            yStartIdx = round(yStartIdx+width*0.1);
            set(app.edit_yStartIdx,'Value',num2str(yStartIdx))
            draw(app,event);
        end

        % Button pushed function: moveYdown
        function moveYdownButtonPushed(app, event)
            global cVD
            
            % size of the angiogram
            [sZ,sX,sY] = size(cVD.angio);
            
            yMIP = str2double(get(app.edit_yEndIdx,'Value'));
            yStartIdx = min(max(str2double(get(app.edit_yStartIdx,'Value')),1),sY);
            yEndIdx = min(max(yStartIdx+yMIP-1,yStartIdx),sY);
            width = yEndIdx - yStartIdx;
            yStartIdx = round(yStartIdx-width*0.1);
            set(app.edit_yStartIdx,'Value',num2str(yStartIdx))
            draw(app,event);
        end

        % Button pushed function: moveZtop
        function moveZtopButtonPushed(app, event)
            global cVD
            
            % size of the angiogram
            [sZ,sX,sY] = size(cVD.angio);
            zStartIdx = min(max(str2double(get(app.edit_zStartIdx,'Value')),1),sZ);
            zMIP = str2double(get(app.edit_zEndIdx,'Value'));
            zEndIdx = min(max(zStartIdx+zMIP-1,zStartIdx),sZ);
            width = zEndIdx - zStartIdx;
            zStartIdx = round(zStartIdx-width*0.1);
            set(app.edit_zStartIdx,'Value',num2str(zStartIdx))
            draw(app,event);
        end

        % Button pushed function: moveZbottom
        function moveZbottomButtonPushed(app, event)
            global cVD
            
            % size of the angiogram
            [sZ,sX,sY] = size(cVD.angio);
            zStartIdx = min(max(str2double(get(app.edit_zStartIdx,'Value')),1),sZ);
            zMIP = str2double(get(app.edit_zEndIdx,'Value'));
            zEndIdx = min(max(zStartIdx+zMIP-1,zStartIdx),sZ);
            width = zEndIdx - zStartIdx;
            zStartIdx = round(zStartIdx+width*0.1);
            set(app.edit_zStartIdx,'Value',num2str(zStartIdx))
            draw(app,event);
        end

        % Value changed function: popupfigureCheckBox
        function popupfigureCheckBoxValueChanged(app, event)
            value = app.popupfigureCheckBox.Value;
            draw(app,event);
        end

        % Value changed function: InspectedCheckBox
        function InspectedCheckBoxValueChanged(app, event)
            global cVD
            value = app.InspectedCheckBox.Value;
            po2pt = get(app.EditField_2,'Value');
            session = app.EditField.Value;
            cVD.pO2ptsBO{session}(po2pt).inspected = value;
        end

        % Value changed function: NotesTextArea
        function NotesTextAreaValueChanged(app, event)
            global cVD
            value = app.NotesTextArea.Value;
            po2pt = get(app.EditField_2,'Value');
            session = app.EditField.Value;
            cVD.pO2ptsBO{session}(po2pt).notes = value;
        end

        % Menu selected function: SaveBranchOrderMenu
        function SaveBranchOrderMenuSelected(app, event)
            global cVD
            if isfield(cVD,'graphfilename')
                if isfield(cVD,'pO2ptsBO')
                    m = matfile([cVD.graphpathname cVD.graphfilename],'Writable',true);
                    m.pO2ptsBO = cVD.pO2ptsBO;
                    m.pO2Seg = cVD.pO2Seg;
                end
            end
        end

        % Button pushed function: displaycurrentAVpathButton
        function displaycurrentAVpathButtonPushed(app, event)
            global cVD
            po2pt = get(app.EditField_2,'Value');
            session = app.EditField.Value;
            current_pt = cVD.pO2pts{session}(po2pt,:);
            
            % size of the angiogram
            [~,sX,sY] = size(cVD.angio);   
            xMIP = str2double(get(app.edit_xEndIdx,'Value'));
            yMIP = str2double(get(app.edit_yEndIdx,'Value'));
            xStart = min(max((current_pt(2)-xMIP/2),1),sX);
            yStart = min(max((current_pt(3)-yMIP/2),1),sY);
            set(app.edit_xStartIdx,'Value',num2str(xStart));
            set(app.edit_yStartIdx,'Value',num2str(yStart));
            set(app.edit_zStartIdx,'Value',num2str(current_pt(1)));
            
            draw(app,event);
        end

        % Menu selected function: GetpathbetweenpointsMenu
        function GetpathbetweenpointsMenuSelected(app, event)
                global cVD
                segInfo = cVD.Graph.segInfo;
                for v = 1:length(cVD.pO2pts)
                  pts = cVD.pO2pts{v};
                  startCap =  app.cappathField1.Value;
                  for uz = 1:size(pts,1)-1
                      uz
                      idx3 = 1;
                      for vz = uz+1:size(pts,1)
                          processedSegments = [];
                          processedEndNodes = [];
                          pO2_pos = pts(uz,:);
                          pO2_pos = [pO2_pos(2) pO2_pos(3) pO2_pos(1)];
                          [~,nodeIdx] = min(sqrt(sum((cVD.Graph.nodes-pO2_pos).^2,2)));
                          nodeIdx
                          currentSegment = cVD.Graph.segInfo.nodeSegN(nodeIdx);
                          startSegment = currentSegment
    % %                       if uz < size(pts,1)
                          targetCap = app.cappathField2.Value;
                          pO2_pos = pts(vz,:);
                          pO2_pos = [pO2_pos(2) pO2_pos(3) pO2_pos(1)];
                          [~,nodeIdx] = min(sqrt(sum((cVD.Graph.nodes-pO2_pos).^2,2)));
                          nodeIdx
                          target_segment = cVD.Graph.segInfo.nodeSegN(nodeIdx)
    %                       end
                          count = 1;
                          branchOrder(currentSegment) = count;
                          currentSegNodes = find(segInfo.nodeSegN == currentSegment);
                          capfound = 0;
                          while(1)
                              if ~isempty(currentSegNodes)
                                  segEndNodes = segInfo.segEndNodes(currentSegment,:);
                                  segEndNodes = setdiff(segEndNodes,processedEndNodes);
                                  segEndNodes = segEndNodes(:);
                                  segmentsNextorder  = [];
                                  for vv=1:length(segEndNodes)
                                    segmentsNextorder = [segmentsNextorder ; find((segInfo.segEndNodes(:,1) == segEndNodes(vv)) | (segInfo.segEndNodes(:,2) == segEndNodes(vv)))];
                                  end
                                  idx = find(segmentsNextorder == target_segment);
                                  if ~isempty(idx) | count > 20
                                      if ~isempty(idx)
                                          cap_path = target_segment;
                                          for ww = 1:count
                                              segs = find(branchOrder == count-ww+1);
                                              current_endNodes = segInfo.segEndNodes(cap_path(end),:); 
                                              for kk = 1:length(segs)
                                                segs_endNodes = segInfo.segEndNodes(segs(kk),:);
                                                [lia1,locb1] = ismember(current_endNodes,segs_endNodes);
                                                if sum(lia1) > 0
                                                    cap_path = [cap_path; segs(kk)];
        %                                             capfound = 1;
                                                    break;
                                                end
                                              end
        %                                       if capfound
        %                                           break;
        %                                       end
                                          end
                                          cap_path = [cap_path; startSegment];
                                      end
                                      break;
                                  end
                                  processedSegments = [processedSegments; currentSegment];
                                  processedEndNodes = [processedEndNodes; segEndNodes];
                                  currentSegment = setdiff(segmentsNextorder,processedSegments);;
                                  count = count+1;
                                  branchOrder(currentSegment) = count;
                              end
                          end
                          if isfield(cVD,'IntrapO2ptspath')
                             cVD.IntrapO2ptspath{v,uz,idx3}.segs= cap_path;
                             cVD.IntrapO2ptspath{v,uz,idx3}.segsLength = sum(cVD.Graph.segInfo.segLen(cap_path));
                             cVD.IntrapO2ptspath{v,uz,idx3}.branchorder = length(cap_path);
                          else
                              cVD.IntrapO2ptspath{1,1,idx3}.segs = cap_path;
                              cVD.IntrapO2ptspath{1,1,idx3}.segsLength = sum(cVD.Graph.segInfo.segLen(cap_path));
                              cVD.IntrapO2ptspath{1,1,idx3}.branchorder = length(cap_path);
                          end
                          idx3 = idx3+1;
    %                       cVD.cap_path = cap_path;
                      end
                   end
                end
%             end
        
        
%             global cVD
%             segInfo = cVD.Graph.segInfo;
%             for v = 1:length(cVD.pO2pts)
%               pts = cVD.pO2pts{v};
%               for uz = 2%:size(pts,1)-1
%                   uz
%                   processedSegments = [];
%                   processedEndNodes = [];
%                   pO2_pos = pts(uz,:);
%                   pO2_pos = [pO2_pos(2) pO2_pos(3) pO2_pos(1)];
%                   [~,nodeIdx] = min(sqrt(sum((cVD.Graph.nodes-pO2_pos).^2,2)));
%                   nodeIdx
%                   currentSegment = cVD.Graph.segInfo.nodeSegN(nodeIdx);
%                   startSegment = currentSegment
%                   if uz < size(pts,1)
%                       pO2_pos = pts(uz+1,:);
%                       pO2_pos = [pO2_pos(2) pO2_pos(3) pO2_pos(1)];
%                       [~,nodeIdx] = min(sqrt(sum((cVD.Graph.nodes-pO2_pos).^2,2)));
%                       nodeIdx
%                       target_segment = cVD.Graph.segInfo.nodeSegN(nodeIdx)
%                   end
%                   count = 1;
%                   branchOrder(currentSegment) = count;
%                   currentSegNodes = find(segInfo.nodeSegN == currentSegment);
%                   capfound = 0;
%                   while(1)
%                       if ~isempty(currentSegNodes)
%                           segEndNodes = segInfo.segEndNodes(currentSegment,:);
%                           segEndNodes = setdiff(segEndNodes,processedEndNodes);
%                           segEndNodes = segEndNodes(:);
%                           segmentsNextorder  = [];
%                           for vv=1:length(segEndNodes)
%                             segmentsNextorder = [segmentsNextorder ; find((segInfo.segEndNodes(:,1) == segEndNodes(vv)) | (segInfo.segEndNodes(:,2) == segEndNodes(vv)))];
%                           end
%                           idx = find(segmentsNextorder == target_segment);
%                           if ~isempty(idx) | count > 20
%                               if ~isempty(idx)
%                                   cap_path = target_segment;
%                                   for ww = 1:count
%                                       segs = find(branchOrder == count-ww+1);
%                                       current_endNodes = segInfo.segEndNodes(cap_path(end),:); 
%                                       for kk = 1:length(segs)
%                                         segs_endNodes = segInfo.segEndNodes(segs(kk),:);
%                                         [lia1,locb1] = ismember(current_endNodes,segs_endNodes);
%                                         if sum(lia1) > 0
%                                             cap_path = [cap_path; segs(kk)];
% %                                             capfound = 1;
%                                             break;
%                                         end
%                                       end
% %                                       if capfound
% %                                           break;
% %                                       end
%                                   end
%                                   cap_path = [cap_path; startSegment]
%                               end
%                               break;
%                           end
%                           processedSegments = [processedSegments; currentSegment];
%                           processedEndNodes = [processedEndNodes; segEndNodes];
%                           currentSegment = setdiff(segmentsNextorder,processedSegments);;
%                           count = count+1;
%                           branchOrder(currentSegment) = count;
%                       end
%                   end
%                end
%             end
        end

        % Value changed function: DisplaycappathCheckBox
        function DisplaycappathCheckBoxValueChanged(app, event)
%             value = app.DisplaycappathCheckBox.Value;
%             if value == 1
%                 global cVD
%                 segInfo = cVD.Graph.segInfo;
%                 for v = 1:length(cVD.pO2pts)
%                   pts = cVD.pO2pts{v};
%                   startCap =  app.cappathField1.Value;
%                   for uz = 1%:size(pts,1)-1
%                       uz
%                       processedSegments = [];
%                       processedEndNodes = [];
%                       pO2_pos = pts(startCap,:);
%                       pO2_pos = [pO2_pos(2) pO2_pos(3) pO2_pos(1)];
%                       [~,nodeIdx] = min(sqrt(sum((cVD.Graph.nodes-pO2_pos).^2,2)));
%                       nodeIdx
%                       currentSegment = cVD.Graph.segInfo.nodeSegN(nodeIdx);
%                       startSegment = currentSegment
% % %                       if uz < size(pts,1)
%                       targetCap = app.cappathField2.Value;
%                       pO2_pos = pts(targetCap,:);
%                       pO2_pos = [pO2_pos(2) pO2_pos(3) pO2_pos(1)];
%                       [~,nodeIdx] = min(sqrt(sum((cVD.Graph.nodes-pO2_pos).^2,2)));
%                       nodeIdx
%                       target_segment = cVD.Graph.segInfo.nodeSegN(nodeIdx)
% %                       end
%                       count = 1;
%                       branchOrder(currentSegment) = count;
%                       currentSegNodes = find(segInfo.nodeSegN == currentSegment);
%                       capfound = 0;
%                       while(1)
%                           if ~isempty(currentSegNodes)
%                               segEndNodes = segInfo.segEndNodes(currentSegment,:);
%                               segEndNodes = setdiff(segEndNodes,processedEndNodes);
%                               segEndNodes = segEndNodes(:);
%                               segmentsNextorder  = [];
%                               for vv=1:length(segEndNodes)
%                                 segmentsNextorder = [segmentsNextorder ; find((segInfo.segEndNodes(:,1) == segEndNodes(vv)) | (segInfo.segEndNodes(:,2) == segEndNodes(vv)))];
%                               end
%                               idx = find(segmentsNextorder == target_segment);
%                               if ~isempty(idx) | count > 20
%                                   if ~isempty(idx)
%                                       cap_path = target_segment;
%                                       for ww = 1:count
%                                           segs = find(branchOrder == count-ww+1);
%                                           current_endNodes = segInfo.segEndNodes(cap_path(end),:); 
%                                           for kk = 1:length(segs)
%                                             segs_endNodes = segInfo.segEndNodes(segs(kk),:);
%                                             [lia1,locb1] = ismember(current_endNodes,segs_endNodes);
%                                             if sum(lia1) > 0
%                                                 cap_path = [cap_path; segs(kk)];
%     %                                             capfound = 1;
%                                                 break;
%                                             end
%                                           end
%     %                                       if capfound
%     %                                           break;
%     %                                       end
%                                       end
%                                       cap_path = [cap_path; startSegment];
%                                   end
%                                   break;
%                               end
%                               processedSegments = [processedSegments; currentSegment];
%                               processedEndNodes = [processedEndNodes; segEndNodes];
%                               currentSegment = setdiff(segmentsNextorder,processedSegments);;
%                               count = count+1;
%                               branchOrder(currentSegment) = count;
%                           end
%                       end
%                       cVD.cap_path = cap_path;
%                    end
%                 end
%             end
            draw(app,event);
        end

        % Value changed function: cappathField1
        function cappathField1ValueChanged(app, event)
%             value = app.cappathField1.Value;
%             DisplaycappathCheckBoxValueChanged(app, event)
            draw(app,event);
        end

        % Value changed function: cappathField2
        function cappathField2ValueChanged(app, event)
%             value = app.cappathField2.Value;
%             DisplaycappathCheckBoxValueChanged(app, event)
            draw(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'chronicVascularDataViz';
            app.menu = uimenu(app.figure1);
            app.menu.Text = 'Menu';
            app.menu_loadAngiogram = uimenu(app.menu);
            app.menu_loadAngiogram.MenuSelectedFcn = createCallbackFcn(app, @menu_loadAngiogram_Callback, true);
            app.menu_loadAngiogram.Text = 'Load angiogram';
            app.menu_loadGraph = uimenu(app.menu);
            app.menu_loadGraph.MenuSelectedFcn = createCallbackFcn(app, @menu_loadGraphMenuSelected, true);
            app.menu_loadGraph.Text = 'Load Graph';
            app.menu_loadStallsInfo = uimenu(app.menu);
            app.menu_loadStallsInfo.MenuSelectedFcn = createCallbackFcn(app, @menu_loadStallsInfoMenuSelected, true);
            app.menu_loadStallsInfo.Text = 'Load Stalls info';
            app.LoadpO2ptsMenu = uimenu(app.menu);
            app.LoadpO2ptsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadpO2ptsMenuSelected, true);
            app.LoadpO2ptsMenu.Text = 'Load pO2 pts';
            app.SaveBranchOrderMenu = uimenu(app.menu);
            app.SaveBranchOrderMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBranchOrderMenuSelected, true);
            app.SaveBranchOrderMenu.Text = 'Save Branch Order';
            app.ToolsMenu = uimenu(app.figure1);
            app.ToolsMenu.Text = 'Tools';
            app.GetbranchorderMenuforpO2Menu = uimenu(app.ToolsMenu);
            app.GetbranchorderMenuforpO2Menu.MenuSelectedFcn = createCallbackFcn(app, @GetbranchorderMenuforpO2MenuSelected, true);
            app.GetbranchorderMenuforpO2Menu.Text = 'Get branch order ';
            app.GetpathbetweenpointsMenu = uimenu(app.ToolsMenu);
            app.GetpathbetweenpointsMenu.MenuSelectedFcn = createCallbackFcn(app, @GetpathbetweenpointsMenuSelected, true);
            app.GetpathbetweenpointsMenu.Text = 'Get path between points';
            app.axes1 = uiaxes(app.figure1);
            app.edit_zStartIdx = uieditfield(app.figure1, 'text');
            app.edit_zStartIdx.ValueChangedFcn = createCallbackFcn(app, @edit_zStartIdxValueChanged, true);
            app.edit_zStartIdx.Value = 'Zstart';
            app.edit_zEndIdx = uieditfield(app.figure1, 'text');
            app.edit_zEndIdx.ValueChangedFcn = createCallbackFcn(app, @edit_zEndIdxValueChanged, true);
            app.edit_zEndIdx.Value = 'ZEnd';
            app.edit_xStartIdx = uieditfield(app.figure1, 'text');
            app.edit_xStartIdx.ValueChangedFcn = createCallbackFcn(app, @edit_xStartIdxValueChanged, true);
            app.edit_xStartIdx.Value = 'XStart';
            app.edit_xEndIdx = uieditfield(app.figure1, 'text');
            app.edit_xEndIdx.ValueChangedFcn = createCallbackFcn(app, @edit_xEndIdxValueChanged, true);
            app.edit_xEndIdx.Value = 'XEnd';
            app.edit_yStartIdx = uieditfield(app.figure1, 'text');
            app.edit_yStartIdx.ValueChangedFcn = createCallbackFcn(app, @edit_yStartIdxValueChanged, true);
            app.edit_yStartIdx.Value = 'YStart';
            app.edit_yEndIdx = uieditfield(app.figure1, 'text');
            app.edit_yEndIdx.ValueChangedFcn = createCallbackFcn(app, @edit_yEndIdxValueChanged, true);
            app.edit_yEndIdx.Value = 'YEnd';
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Z:';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'X:';
            app.text4 = uilabel(app.figure1);
            app.text4.Text = 'Y:';
            app.AdjustcontrastPanel = uipanel(app.figure1);
            app.AdjustcontrastPanel.Title = 'Adjust contrast';
            app.minEditFieldLabel = uilabel(app.AdjustcontrastPanel);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.AdjustcontrastPanel, 'numeric');
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.maxEditFieldLabel = uilabel(app.AdjustcontrastPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.AdjustcontrastPanel, 'numeric');
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @maxEditFieldValueChanged, true);
            app.ZstartSliderLabel = uilabel(app.figure1);
            app.ZstartSliderLabel.Text = 'Z start';
            app.ZstartSlider = uislider(app.figure1);
            app.ZstartSlider.ValueChangedFcn = createCallbackFcn(app, @ZstartSliderValueChanged, true);
            app.DisplaystallsCheckBox = uicheckbox(app.figure1);
            app.DisplaystallsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplaystallsCheckBoxValueChanged, true);
            app.DisplaystallsCheckBox.Text = 'Display stalls';
            app.DisplaypO2ptsCheckBox = uicheckbox(app.figure1);
            app.DisplaypO2ptsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplaypO2ptsCheckBoxValueChanged, true);
            app.DisplaypO2ptsCheckBox.Text = 'Display pO2 pts';
            app.GraphPanel = uipanel(app.figure1);
            app.GraphPanel.Title = 'Graph';
            app.DisplayGraphCheckBox = uicheckbox(app.GraphPanel);
            app.DisplayGraphCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayGraphCheckBoxValueChanged, true);
            app.DisplayGraphCheckBox.Text = 'Display Graph';
            app.DisplayCapillaryCheckBox = uicheckbox(app.GraphPanel);
            app.DisplayCapillaryCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayCapillaryCheckBoxValueChanged, true);
            app.DisplayCapillaryCheckBox.Text = 'Display Capillary';
            app.DisplayArteryCheckBox = uicheckbox(app.GraphPanel);
            app.DisplayArteryCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayArteryCheckBoxValueChanged, true);
            app.DisplayArteryCheckBox.Text = 'Display Artery';
            app.DisplayVeinCheckBox = uicheckbox(app.GraphPanel);
            app.DisplayVeinCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayVeinCheckBoxValueChanged, true);
            app.DisplayVeinCheckBox.Text = 'Display Vein';
            app.DisplayAVpathCheckBox = uicheckbox(app.figure1);
            app.DisplayAVpathCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayAVpathCheckBoxValueChanged, true);
            app.DisplayAVpathCheckBox.Text = 'Display AV path';
            app.EditField_2Label = uilabel(app.figure1);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.figure1, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 1;
            app.EditFieldLabel = uilabel(app.figure1);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.figure1, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.moveXleft = uibutton(app.figure1, 'push');
            app.moveXleft.ButtonPushedFcn = createCallbackFcn(app, @moveXleftButtonPushed, true);
            app.moveXleft.Text = '< X';
            app.moveXright = uibutton(app.figure1, 'push');
            app.moveXright.ButtonPushedFcn = createCallbackFcn(app, @moveXrightButtonPushed, true);
            app.moveXright.Text = 'X >';
            app.moveYup = uibutton(app.figure1, 'push');
            app.moveYup.ButtonPushedFcn = createCallbackFcn(app, @moveYupButtonPushed, true);
            app.moveYup.Text = {'Y'; 'v'};
            app.moveYdown = uibutton(app.figure1, 'push');
            app.moveYdown.ButtonPushedFcn = createCallbackFcn(app, @moveYdownButtonPushed, true);
            app.moveYdown.Text = {'^'; 'Y'};
            app.moveZtop = uibutton(app.figure1, 'push');
            app.moveZtop.ButtonPushedFcn = createCallbackFcn(app, @moveZtopButtonPushed, true);
            app.moveZtop.Text = '< Z';
            app.moveZbottom = uibutton(app.figure1, 'push');
            app.moveZbottom.ButtonPushedFcn = createCallbackFcn(app, @moveZbottomButtonPushed, true);
            app.moveZbottom.Text = 'Z >';
            app.ABL = uilabel(app.figure1);
            app.ABL_um = uilabel(app.figure1);
            app.VBL = uilabel(app.figure1);
            app.VBL_um = uilabel(app.figure1);
            app.ABOLabel = uilabel(app.figure1);
            app.ABOLabel.Text = 'ABO';
            app.VBOLabel = uilabel(app.figure1);
            app.VBOLabel.Text = 'VBO';
            app.SessionLabel = uilabel(app.figure1);
            app.SessionLabel.Text = 'Z:';
            app.NumberLabel = uilabel(app.figure1);
            app.NumberLabel.Text = 'Z:';
            app.StartpositionLabel = uilabel(app.figure1);
            app.StartpositionLabel.Text = 'Start position';
            app.WidthLabel = uilabel(app.figure1);
            app.WidthLabel.Text = 'Width';
            app.popupfigureCheckBox = uicheckbox(app.figure1);
            app.popupfigureCheckBox.ValueChangedFcn = createCallbackFcn(app, @popupfigureCheckBoxValueChanged, true);
            app.popupfigureCheckBox.Text = 'Open in figure';
            app.InspectedCheckBox = uicheckbox(app.figure1);
            app.InspectedCheckBox.ValueChangedFcn = createCallbackFcn(app, @InspectedCheckBoxValueChanged, true);
            app.InspectedCheckBox.Text = 'Inspected ';
            app.NotesTextAreaLabel = uilabel(app.figure1);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.figure1);
            app.NotesTextArea.ValueChangedFcn = createCallbackFcn(app, @NotesTextAreaValueChanged, true);
            app.displaycurrentAVpathButton = uibutton(app.figure1, 'push');
            app.displaycurrentAVpathButton.ButtonPushedFcn = createCallbackFcn(app, @displaycurrentAVpathButtonPushed, true);
            app.displaycurrentAVpathButton.Text = 'display current AV path ';
            app.DisplaycappathCheckBox = uicheckbox(app.figure1);
            app.DisplaycappathCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplaycappathCheckBoxValueChanged, true);
            app.DisplaycappathCheckBox.Text = 'Display cap path';
            app.EditField_3Label = uilabel(app.figure1);
            app.EditField_3Label.Text = '';
            app.cappathField1 = uieditfield(app.figure1, 'numeric');
            app.cappathField1.ValueChangedFcn = createCallbackFcn(app, @cappathField1ValueChanged, true);
            app.cappathField1.Value = 1;
            app.EditField_4Label = uilabel(app.figure1);
            app.EditField_4Label.Text = '';
            app.cappathField2 = uieditfield(app.figure1, 'numeric');
            app.cappathField2.ValueChangedFcn = createCallbackFcn(app, @cappathField2ValueChanged, true);
            app.cappathField2.Value = 2;
            app.BranchOrderLabel = uilabel(app.figure1);
            app.BranchOrderLabel.Text = 'Branch Order';
            app.lengthLabel = uilabel(app.figure1);
            app.lengthLabel.Text = 'length';
            app.IntraCapBranchOrder = uilabel(app.figure1);
            app.IntraCapBranchLength = uilabel(app.figure1);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VascularDataViz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @chronicVascularDataViz_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end